classdef Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ImageAxes    matlab.ui.control.UIAxes
        LoadButton   matlab.ui.control.Button
        ImageAxes_2  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            close all;
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                [im,amap] = imread('corn.tif', 2);
            else
                try
                    [im,amap] = imread(imagefile);
                    
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end
            end
            
            if length(amap) ~= 0
                im  =  ind2rgb(im,amap);          %we have a indexed image and convert it to rgb
            end
            [m, n,o] = size(im);
%             im = double(im);
            
            imagesc(app.ImageAxes,im);
            MyFigure = figure;
            imshow(im)
            [r c] = ginput(4)
%             ed = edge(rgb2gray(im));
%             if max(max(max(im))) <= 1
%                 im = im*255;
%             end
%             for i = 1 : m
%                 for j = 1 : n
%                     if ed(i,j)==1
%                         k = i;
%                         p = j;
%                         res(i,j,1) = 1;
%                         res(i,j,2) = 0;
%                         res(i,j,3) = 0;
%                     end
%                 end
%             end
            bw = magicwand(im, round(c'), round(r'), 50);
            %             bw = roipoly(im, r,c);
            res = zeros(m,n,3);
            
            for i = 1 : m
                for j = 1 : n
                    if bw(i,j)==1
                        res(i,j,1) = double(im(i,j,1))/255;
                        res(i,j,2) = double(im(i,j,2))/255;
                        res(i,j,3) = double(im(i,j,3))/255;
                    end
                end
            end
            saveFileApp('tototototttotottto',res);
            imagesc(app.ImageAxes_2,res);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                updateimage(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.ImageAxes_2 = uiaxes(app.UIFigure);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end